classdef track < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TrackedTextUIFigure        matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        TrackingTab                matlab.ui.container.Tab
        ExportPanel                matlab.ui.container.Panel
        ExportButton               matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        EditPanel                  matlab.ui.container.Panel
        SelectedCalcDropDownLabel  matlab.ui.control.Label
        SelectedCalcDropDown       matlab.ui.control.DropDown
        NewCalcButton              matlab.ui.control.Button
        SizeEditFieldLabel         matlab.ui.control.Label
        SizeEditField              matlab.ui.control.NumericEditField
        PreviewLabel               matlab.ui.control.Label
        ChooseColorButton          matlab.ui.control.Button
        Preview                    matlab.ui.control.Label
        PanelSpinner               matlab.ui.container.Panel
        FrameSpinnerLabel          matlab.ui.control.Label
        FrameSpinner               matlab.ui.control.Spinner
        ImportPanel                matlab.ui.container.Panel
        OpenButton                 matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        ResizeTab                  matlab.ui.container.Tab
        ax                         matlab.ui.control.UIAxes
        ResizePanel                matlab.ui.container.Panel
        OpenButton_2               matlab.ui.control.Button
        SideButtonGroup            matlab.ui.container.ButtonGroup
        LeftButton                 matlab.ui.control.RadioButton
        RightButton                matlab.ui.control.RadioButton
        TopButton                  matlab.ui.control.RadioButton
        BottomButton               matlab.ui.control.RadioButton
        MarginSpinnerLabel         matlab.ui.control.Label
        MarginSpinner              matlab.ui.control.Spinner
        ExportButton_2             matlab.ui.control.Button
    end

    
    properties (Access = private)
        list_calc % list of calcs
        vid % videoreader object
        vid2
        nFrames
        nFrames2
        lft_lim_plt
        rgt_lim_plt
        top_lim_plt
        bot_lim_plt
        lft_lim
        rgt_lim
        top_lim
        bot_lim
    end
    
    methods (Access = private)
        
        function file_opened(app)
            %1st frame display
            frame = read(app.vid,1);
            app.FrameSpinner.Value = 1;
            app.Image.ImageSource = frame;
            
            %set frame limit
            app.nFrames = app.vid.NumFrames;
            app.FrameSpinner.Limits = [1,app.nFrames];
        end
        
        function newItems(app)
            sz = size(app.list_calc);
            items = cell(1,sz(2));
            items_data = 1:sz(2);
            for i = items_data
                items(i) = app.list_calc(i).text;
            end
            app.SelectedCalcDropDown.Items = items;
            app.SelectedCalcDropDown.ItemsData = items_data;
            app.SelectedCalcDropDown.Value = sz(2);
        end
        
        function preview(app)
            idx = app.SelectedCalcDropDown.Value;
            app.Preview.FontSize = app.list_calc(idx).sz;
            app.Preview.Text = app.list_calc(idx).text;
            app.Preview.FontColor = app.list_calc(idx).color;
        end
        
        
        function set_track(app,i)
            if i <= app.nFrames
                idx = app.SelectedCalcDropDown.Value;
                frame = read(app.vid,i);
                imshow(frame);
                [x,y,button] = ginput(1);
                if button ~= 13
                    switch button
                        case 1
                            app.set_track(i+1);
                            app.list_calc(idx).track(i,:) = [x,y];
                        case 3
                            app.set_track(i+1);
                    end
                else
                    close
                end
            end
        end
        
        function outpoutFile(app)
            list_frames = read(app.vid);
            for calc = app.list_calc
                calc.color = floor(calc.color.*255);
                for i = 1:app.nFrames
                    frame = list_frames(:,:,:,i);
                    if not(isequal(calc.track(i,:),[0 0]))
                        frame1 = insertText(frame,calc.track(i,:),calc.text,"FontSize",calc.sz,"TextColor",calc.color,"BoxOpacity",0,"AnchorPoint","Center");
                    else
                        frame1 = frame;
                    end
                    list_frames(:,:,:,i) = frame1;
                end
            end
            
            filter = {'*.avi';'*.gif'};
            [file,path] = uiputfile(filter);
            
            if strcmp(file(end-2:end),'avi')
                vidWriter = vision.VideoFileWriter(strcat(path,file));
                
                %audio
                [s,~] = audioread(strcat(app.vid.Path,'/',app.vid.Name));
            
                vidWriter.AudioInputPort = true;
                vidWriter.FrameRate = app.vid.FrameRate;
                vidWriter.VideoCompressor='DV Video Encoder';
            
                Audiostep = floor(size(s)/app.nFrames);
                for i = 1:app.nFrames
                    sample = s(1+(i-1)*Audiostep:i*Audiostep,:);
                    step(vidWriter,list_frames(:,:,:,i),sample);
                end
                release(vidWriter);
                
            elseif strcmp(file(end-2:end),'gif')
                filename = strcat(path,file);
                for i = 1:3:app.nFrames
                    im = list_frames(:,:,:,i);
                    [imind,cm] = rgb2ind(im,32);
                    if i == 1
                        imwrite(imind,cm,filename,'gif','LoopCount',Inf,'DelayTime',0.1);
                    else
                        imwrite(imind,cm,filename,'gif','WriteMode','append','DelayTime',0.1);
                    end
                end
            end
            
        end
        
        function file_opened_2(app)
            app.nFrames2 = app.vid2.NumFrames;
            %1st frame display
            frame = read(app.vid2,1);
            w = app.vid2.Width;
            h = app.vid2.Height;
            imshow(frame,'parent',app.ax);
            %left
            app.lft_lim = 0;
            app.lft_lim_plt = plot(app.ax,[0+0i,0+h*1i],'-g');
            %right
            app.rgt_lim = 0;
            app.rgt_lim_plt = plot(app.ax,[w+0i,w+h*1i],'-g');
            %top
            app.top_lim = 0;
            app.top_lim_plt = plot(app.ax,[0+1i,w+1i],'-g');
            %bot
            app.bot_lim = 0;
            app.bot_lim_plt = plot(app.ax,[0+h*1i,w+h*1i],'-g');
            
            app.MarginSpinner.Enable = true;
            app.MarginSpinnerLabel.Enable = true;
            app.LeftButton.Enable = true;
            app.RightButton.Enable = true;
            app.TopButton.Enable = true;
            app.BottomButton.Enable = true;
            app.ExportButton_2.Enable = true;
            app.MarginSpinner.Limits = [0,w];
        end
        
        function plt_lim(app)
            w = app.vid2.Width;
            h = app.vid2.Height;
            %left
            app.lft_lim_plt.XData = app.lft_lim*ones(1,2);
            %right
            app.rgt_lim_plt.XData = (w-app.rgt_lim)*ones(1,2);
            %top
            app.top_lim_plt.YData = app.top_lim*ones(1,2);
            %bottom
            app.bot_lim_plt.YData = (h-app.bot_lim)*ones(1,2);
        end
        
        function outpoutFile2(app)
            list_frames = read(app.vid2);
            
            list_frames = list_frames(1+app.top_lim:end-app.bot_lim,1+app.lft_lim:end-app.rgt_lim,:,:);
            
            filter = {'*.avi'};
            [file,path] = uiputfile(filter);
            
            vidWriter = vision.VideoFileWriter(strcat(path,file));
            
            %audio
            [s,~] = audioread(strcat(app.vid2.Path,'/',app.vid2.Name));
        
            vidWriter.AudioInputPort = true;
            vidWriter.FrameRate = app.vid2.FrameRate;
            vidWriter.VideoCompressor='DV Video Encoder';
        
            Audiostep = floor(size(s)/app.nFrames2);
            for i = 1:app.nFrames2
                sample = s(1+(i-1)*Audiostep:i*Audiostep,:);
                step(vidWriter,list_frames(:,:,:,i),sample);
            end
            release(vidWriter);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            filter = {'*.mp4';'*.avi'};
            [file,path] = uigetfile(filter);
            if file ~= 0
                app.vid = VideoReader(strcat(path,file));
                app.file_opened()
            end
        end

        % Value changed function: FrameSpinner
        function FrameSpinnerValueChanged(app, event)
            value = app.FrameSpinner.Value;
            frame = read(app.vid,value);
            app.Image.ImageSource = frame;
        end

        % Button pushed function: NewCalcButton
        function NewCalcButtonPushed(app, event)
            name = inputdlg('Name of the calc','New Calc');
            if ~isempty(name)
                calc.text = name;
                calc.track = zeros(app.nFrames,2);
                calc.color = [1,1,1];
                calc.sz = 14;
                app.list_calc = [app.list_calc,calc];              
            end
            app.newItems();
            app.ChooseColorButton.Enable = true;
            app.SizeEditField.Value = 14;
            app.preview();
        end

        % Button pushed function: ChooseColorButton
        function ChooseColorButtonPushed(app, event)
            c = uisetcolor;
            idx = app.SelectedCalcDropDown.Value;
            app.list_calc(idx).color = c;
            app.preview();
        end

        % Value changed function: SizeEditField
        function SizeEditFieldValueChanged(app, event)
            value = app.SizeEditField.Value;
            idx = app.SelectedCalcDropDown.Value;
            app.list_calc(idx).sz = value;
            app.preview();
        end

        % Value changed function: SelectedCalcDropDown
        function SelectedCalcDropDownValueChanged(app, event)
            app.preview();
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            %set path
            i = app.FrameSpinner.Value;
            app.set_track(i);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            app.Label.Text = 'In progress...';
            app.outpoutFile();
            app.Label.Text = 'Done !';
        end

        % Button pushed function: OpenButton_2
        function OpenButton_2Pushed(app, event)
            [file,path] = uigetfile('*.mp4');
            if file ~= 0
                app.vid2 = VideoReader(strcat(path,file));
                app.file_opened_2();
            end
        end

        % Value changed function: MarginSpinner
        function MarginSpinnerValueChanged(app, event)
            value = app.MarginSpinner.Value;
            but = app.SideButtonGroup.SelectedObject.Text;
            switch but
                case 'Left'
                    app.lft_lim = value;
                case 'Right'
                    app.rgt_lim = value;
                case 'Top'
                    app.top_lim = value;
                case 'Bottom'
                    app.bot_lim = value;
            end
            app.plt_lim();
        end

        % Selection changed function: SideButtonGroup
        function SideButtonGroupSelectionChanged(app, event)
            selectedButton = app.SideButtonGroup.SelectedObject;
            text = selectedButton.Text;
            w = app.vid2.Width;
            h = app.vid2.Height;
            switch text
                case 'Left'
                    app.MarginSpinner.Value = app.lft_lim;
                    app.MarginSpinner.Limits = [0,w];
                case 'Right'
                    app.MarginSpinner.Value = app.rgt_lim;
                    app.MarginSpinner.Limits = [0,w];
                case 'Top'
                    app.MarginSpinner.Value = app.top_lim;
                    app.MarginSpinner.Limits = [0,h];
                case 'Bottom'
                    app.MarginSpinner.Value = app.bot_lim;
                    app.MarginSpinner.Limits = [0,h];
            end
        end

        % Button pushed function: ExportButton_2
        function ExportButton_2Pushed(app, event)
            app.outpoutFile2();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TrackedTextUIFigure = uifigure('Visible', 'off');
            app.TrackedTextUIFigure.Name = 'TrackedText';
            app.TabGroup = uitabgroup(app.TrackedTextUIFigure);
            app.TrackingTab = uitab(app.TabGroup);
            app.TrackingTab.Title = 'Tracking';
            app.ExportPanel = uipanel(app.TrackingTab);
            app.ExportPanel.Title = 'Export';
            app.ExportButton = uibutton(app.ExportPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.Label = uilabel(app.ExportPanel);
            app.Label.Text = '';
            app.EditPanel = uipanel(app.TrackingTab);
            app.EditPanel.Title = 'Edit';
            app.SelectedCalcDropDownLabel = uilabel(app.EditPanel);
            app.SelectedCalcDropDownLabel.Text = 'Selected Calc';
            app.SelectedCalcDropDown = uidropdown(app.EditPanel);
            app.SelectedCalcDropDown.Items = {};
            app.SelectedCalcDropDown.Editable = 'on';
            app.SelectedCalcDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedCalcDropDownValueChanged, true);
            app.SelectedCalcDropDown.Value = {};
            app.NewCalcButton = uibutton(app.EditPanel, 'push');
            app.NewCalcButton.ButtonPushedFcn = createCallbackFcn(app, @NewCalcButtonPushed, true);
            app.NewCalcButton.Text = 'New Calc';
            app.SizeEditFieldLabel = uilabel(app.EditPanel);
            app.SizeEditFieldLabel.Text = 'Size';
            app.SizeEditField = uieditfield(app.EditPanel, 'numeric');
            app.SizeEditField.Limits = [1 500];
            app.SizeEditField.ValueChangedFcn = createCallbackFcn(app, @SizeEditFieldValueChanged, true);
            app.SizeEditField.Value = 14;
            app.PreviewLabel = uilabel(app.EditPanel);
            app.PreviewLabel.Text = 'Preview';
            app.ChooseColorButton = uibutton(app.EditPanel, 'push');
            app.ChooseColorButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseColorButtonPushed, true);
            app.ChooseColorButton.Enable = 'off';
            app.ChooseColorButton.Text = 'Choose Color';
            app.Preview = uilabel(app.EditPanel);
            app.Preview.Text = '';
            app.PanelSpinner = uipanel(app.TrackingTab);
            app.FrameSpinnerLabel = uilabel(app.PanelSpinner);
            app.FrameSpinnerLabel.Text = 'Frame';
            app.FrameSpinner = uispinner(app.PanelSpinner);
            app.FrameSpinner.Limits = [1 100];
            app.FrameSpinner.ValueDisplayFormat = '%.0f';
            app.FrameSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameSpinnerValueChanged, true);
            app.FrameSpinner.Value = 1;
            app.ImportPanel = uipanel(app.TrackingTab);
            app.ImportPanel.Title = 'Import';
            app.OpenButton = uibutton(app.ImportPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.Image = uiimage(app.TrackingTab);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.ResizeTab = uitab(app.TabGroup);
            app.ResizeTab.Title = 'Resize';
            app.ax = uiaxes(app.ResizeTab);
            title(app.ax, '')
            xlabel(app.ax, '')
            ylabel(app.ax, '')
            app.ax.XTick = [];
            app.ax.YTick = [];
            app.ResizePanel = uipanel(app.ResizeTab);
            app.ResizePanel.Title = 'Resize';
            app.OpenButton_2 = uibutton(app.ResizePanel, 'push');
            app.OpenButton_2.ButtonPushedFcn = createCallbackFcn(app, @OpenButton_2Pushed, true);
            app.OpenButton_2.Text = 'Open';
            app.SideButtonGroup = uibuttongroup(app.ResizePanel);
            app.SideButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SideButtonGroupSelectionChanged, true);
            app.SideButtonGroup.Title = 'Side';
            app.LeftButton = uiradiobutton(app.SideButtonGroup);
            app.LeftButton.Enable = 'off';
            app.LeftButton.Text = 'Left';
            app.LeftButton.Value = true;
            app.RightButton = uiradiobutton(app.SideButtonGroup);
            app.RightButton.Enable = 'off';
            app.RightButton.Text = 'Right';
            app.TopButton = uiradiobutton(app.SideButtonGroup);
            app.TopButton.Enable = 'off';
            app.TopButton.Text = 'Top';
            app.BottomButton = uiradiobutton(app.SideButtonGroup);
            app.BottomButton.Enable = 'off';
            app.BottomButton.Text = 'Bottom';
            app.MarginSpinnerLabel = uilabel(app.ResizePanel);
            app.MarginSpinnerLabel.Enable = 'off';
            app.MarginSpinnerLabel.Text = 'Margin';
            app.MarginSpinner = uispinner(app.ResizePanel);
            app.MarginSpinner.Limits = [0 1];
            app.MarginSpinner.ValueChangedFcn = createCallbackFcn(app, @MarginSpinnerValueChanged, true);
            app.MarginSpinner.Enable = 'off';
            app.ExportButton_2 = uibutton(app.ResizePanel, 'push');
            app.ExportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_2Pushed, true);
            app.ExportButton_2.Enable = 'off';
            app.ExportButton_2.Text = 'Export';
            % Show the figure after all components are created
            app.TrackedTextUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = track

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TrackedTextUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TrackedTextUIFigure)
        end
    end
end